classdef interfaz < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        NameofstudentEditField        matlab.ui.control.EditField
        NameofstudentEditFieldLabel   matlab.ui.control.Label
        lamparita                     matlab.ui.control.Lamp
        GrabandoLabel                 matlab.ui.control.Label
        Image                         matlab.ui.control.Image
        EndassistanceButton           matlab.ui.control.Button
        Label                         matlab.ui.control.Label
        Bienvenidoatomalista300Label  matlab.ui.control.Label
        RecordButton                  matlab.ui.control.Button
        ShowreportButton              matlab.ui.control.Button
        AddnewstudentButton           matlab.ui.control.Button
        StartassistanceButton         matlab.ui.control.Button
        UIAxes                        matlab.ui.control.UIAxes
        ContextMenu                   matlab.ui.container.ContextMenu
        Menu                          matlab.ui.container.Menu
        Menu2                         matlab.ui.container.Menu
        ContextMenu2                  matlab.ui.container.ContextMenu
        Menu_2                        matlab.ui.container.Menu
        Menu2_2                       matlab.ui.container.Menu
    end

    
    methods (Access = public)
        function compare(app,audio)
        dir_2 = "C:\Users\Tina\Documents\MATLAB\proyecto_final\";
        audio_file= fullfile(dir_2, audio);
        filtered_audio = app.filter_audio(audio_file);
        
        fprintf("\n================================================\n\n")
        
        % myDir = uigetdir; %gets directory
        myDir = "C:\Users\Tina\Documents\MATLAB\proyecto_final\DB";
        myFiles = dir(myDir);
        
        corrs = zeros(1, length(myFiles)-2);
        names = cell(1, length(myFiles)-2);
        for k = 3:length(myFiles)
            baseFileName = myFiles(k).name;
            fullFileName = fullfile(myDir, baseFileName);
            % fprintf(1, 'Now reading %s\n', fullFileName);
          
            curr_filtered = app.filter_audio(fullFileName);
            
            idx = k - 2;
            correlation = xcorr(filtered_audio, curr_filtered);
            corrs(idx) = max(correlation((length(correlation)+1)/2 - 5:(length(correlation)+1)/2 + 5));
            names{idx} = fullFileName;
            corrs(idx) = max(correlation);
          
        
            % figure(idx);
            % plot(correlation)
        
        end
        m = 0;
        best = "ola";
        for i = 1:length(myFiles)-2
            name = names{i};
            corr = corrs(i);
            if corr>m
                best = name;
                m = corr;
            end
         
           % fprintf("%s: %f \n", name, corr)
        end

        nombre_1 = strrep(best,"C:\Users\Tina\Documents\MATLAB\proyecto_final\DB\", '');
        nombre_2 = strrep(nombre_1,".mp3", '');
        nombre_3 = strrep(nombre_2,".mp4", '');
        nombre_real = strrep(nombre_3,".ogg", '');
        
        
        app.Pegar_a_lista(nombre_real,dir_2)
        end

        
       function filtered = filter_audio(app,filename)
    
            % Tomamos el audio introducido en el llamado a lista 
            [y,f]= audioread(filename);
            
            S = size(y);
            L = S(1);
            half = floor(L/2 + 1);
            
            if S(2) == 2
                y = 1/2*y(:, 1) + 1/2*y(:, 2);
            end
        
            % % Normalizamos la señal
            % y = y/max(y);
        
            % Amplificamos la señal
            y = 10*y;
            
            % Le aplicamos ventana al audio 
            hanning_window = hanning(L);
            y_windowed = hanning_window .* y;
            
            % Lo pasamos al dominio de la frecuencia
            y_frequency = abs(fft(y_windowed))';
            y_frequency = y_frequency(1:half);
        
            y_frequency = y_frequency/max(y_frequency);
            
            % Diseñamos el filtro:
            % La voz humana está en el intervalo 85 a 255 Hz
            % Nuestra frecuencia de muestreo es de 16000 Hz
            % Las frecuencias resultantes son:
            % 85/16000 = 0.0053125
            % 255/16000 = 0.0159375
            
            H_banda = zeros(1, half);
            low = floor(85/f * L);
            high = ceil(255/f * L);
            
            % Creamos el filtro pasabanda
            H_banda(low:high) = 1;
            
            % === Obtenemos el filtro muestreado === %

            H = [H_banda fliplr(H_banda(2:half))];
            M = length(H);
            H = H.*exp(-1i*2*pi.*(0:M-1).*((M-1)/2)./M);
            
            %le sacamos la inversa de la transformada rapida de fourier
            h = ifft(H);
            
            %el filtro va a tener el mismo tamaño que el audio en el
            %dominio de la frecuencia
            f_vec=(0:half-1)./half;
            
            % lo ponemos en el dominio de la frecuencia
            H_banda = freqz(real(h),1,f_vec.*(2*pi));
            
            % === Fin del muestreo del filtro === %

            % Filtramos la señal
            filtered = H_banda .* y_frequency;
            
            
            % figure(1);
            % F = linspace(0, 0.5, L/2+1);
            % 
            % subplot(2, 1, 1);
            % plot(F, y_frequency);
            % 
            % subplot(2, 1, 2);
            % plot(F, y_filtered);

            end
            function Pegar_a_lista(app, nombre,dir)

        archivo = 'Lista.txt';

    
       path = fullfile(dir, archivo);

      
        lista = fopen(path, 'a');

        if lista == -1
            errordlg('No puedo abrir archivo', 'Error');
        else
           
            fprintf(lista, '%s\n', nombre);

            fclose(lista);
        end
    end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: AddnewstudentButton
        function AddnewstudentButtonPushed(app, event)


            recObj = audiorecorder;
            recDuration = 5;
            app.lamparita.Color = [0.39,0.83,0.07]; 

            recordblocking(recObj,recDuration);
            disp("End of recording.")
            app.lamparita.Color = [1.00,0.00,0.00];
            
            y = getaudiodata(recObj);
            s = strcat(app.NameofstudentEditField.Value,'.mp3');
            myDir = "C:\Users\Tina\Documents\MATLAB\proyecto_final\DB\";
            path = strcat(myDir,s);
            audiowrite(path,y,8000)
            
            [y,Fs] = audioread(path);

             % Plotear la señal
            time = (0:length(y)-1) / get(recObj, 'SampleRate');
            plot(app.UIAxes, time, y);
            xlabel(app.UIAxes, 'Tiempo (s)');
            ylabel(app.UIAxes, 'Amplitud');
            title(app.UIAxes, 'Forma de Onda del Audio Grabado');
            
            
            sound(y,Fs)
        end

        % Button pushed function: RecordButton
        function RecordButtonPushed(app, event)
           
                recObj = audiorecorder(16000, 16, 1);
                recDuration = 3;
                app.lamparita.Color = [0.39,0.83,0.07];
                disp("Begin speaking.")
                recordblocking(recObj,recDuration);
                disp("End of recording.")
                app.lamparita.Color = [1.00,0.00,0.00];
                y = getaudiodata(recObj);
                audiowrite('nuevo.mp3',y,16000)
                app.compare('nuevo.mp3')
           
        end

        % Button pushed function: StartassistanceButton
        function StartassistanceButtonPushed(app, event)
           app.RecordButton.Enable = 'on';
          
        end

        % Button pushed function: EndassistanceButton
        function EndassistanceButtonPushed(app, event)
             set(app.RecordButton, 'Enable', 'off');
        end

        % Button pushed function: ShowreportButton
        function ShowreportButtonPushed(app, event)
         
         %Tomamos el archivo de la lista
         nombre = 'Lista.txt';

         myDir = "C:\Users\Tina\Documents\MATLAB\proyecto_final\";
        
         %Lo tomamos de la dirección dond está almacenado
        path = fullfile(myDir, nombre);

        % lo abrimos en modo lectura
        archivo = fopen(path, 'r');

        % Check if the file was opened successfully
        if archivo == -1
            disp("No se pudo abrir el archivo")
        else
            % Se lee los nombres
            Nombres = fread(archivo, '*char');

            % se cierra el archivo
            fclose(archivo);

            % Se meustra en la Command Window
            fprintf('Hoy asistieron a clase:\n%s\n', Nombres);
        end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Señal')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.StartassistanceButton = uibutton(app.UIFigure, 'push');
            app.StartassistanceButton.ButtonPushedFcn = createCallbackFcn(app, @StartassistanceButtonPushed, true);
            app.StartassistanceButton.Text = 'Start assistance';
            app.AddnewstudentButton = uibutton(app.UIFigure, 'push');
            app.AddnewstudentButton.ButtonPushedFcn = createCallbackFcn(app, @AddnewstudentButtonPushed, true);
            app.AddnewstudentButton.Text = 'Add new student';
            app.ShowreportButton = uibutton(app.UIFigure, 'push');
            app.ShowreportButton.ButtonPushedFcn = createCallbackFcn(app, @ShowreportButtonPushed, true);
            app.ShowreportButton.Text = 'Show report';
            app.RecordButton = uibutton(app.UIFigure, 'push');
            app.RecordButton.ButtonPushedFcn = createCallbackFcn(app, @RecordButtonPushed, true);
            app.RecordButton.Enable = 'off';
            app.RecordButton.Text = 'Record';
            app.Bienvenidoatomalista300Label = uilabel(app.UIFigure);
            app.Bienvenidoatomalista300Label.Text = 'Bienvenido a tomalista300';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Para registrar la asistencia presione ´Start assitance´ y para cada estudiante que quiera registrar presione ''Grabar''. Para registrar a un nuevo estudiante escriba el nombre y presione ''Add new student'' ';
            app.EndassistanceButton = uibutton(app.UIFigure, 'push');
            app.EndassistanceButton.ButtonPushedFcn = createCallbackFcn(app, @EndassistanceButtonPushed, true);
            app.EndassistanceButton.Text = 'End assistance';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, '_f2c7144f-e7eb-4bb1-a2bf-358bb16a999b.jpg');
            app.GrabandoLabel = uilabel(app.UIFigure);
            app.GrabandoLabel.Text = 'Recording';
            app.lamparita = uilamp(app.UIFigure);
            app.NameofstudentEditFieldLabel = uilabel(app.UIFigure);
            app.NameofstudentEditFieldLabel.Text = 'Name of student';
            app.NameofstudentEditField = uieditfield(app.UIFigure, 'text');
            app.NameofstudentEditField.Value = 'Default';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            app.ContextMenu2 = uicontextmenu(app.UIFigure);
            app.Menu_2 = uimenu(app.ContextMenu2);
            app.Menu_2.Text = 'Menu';
            app.Menu2_2 = uimenu(app.ContextMenu2);
            app.Menu2_2.Text = 'Menu2';
            % Assign app.ContextMenu2
            app.lamparita.ContextMenu = app.ContextMenu2;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = interfaz

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end